classdef colorphase < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColorphaseUIFigure         matlab.ui.Figure
        Menu                       matlab.ui.container.Menu
        Menu_2                     matlab.ui.container.Menu
        Menu_4                     matlab.ui.container.Menu
        Menu_3                     matlab.ui.container.Menu
        Menu_5                     matlab.ui.container.Menu
        Menu_7                     matlab.ui.container.Menu
        Menu_6                     matlab.ui.container.Menu
        Panel                      matlab.ui.container.Panel
        ModePanel                  matlab.ui.container.ButtonGroup
        Mode1Butten                matlab.ui.control.ToggleButton
        Mode2Butten                matlab.ui.control.ToggleButton
        WavelengthPanel            matlab.ui.container.Panel
        Lambda1Value               matlab.ui.control.NumericEditField
        Lambda1ChooseButten        matlab.ui.control.StateButton
        Lambda2ChooseButten        matlab.ui.control.StateButton
        Lambda3ChooseButten        matlab.ui.control.StateButton
        Lambda2Value               matlab.ui.control.NumericEditField
        Lambda3Value               matlab.ui.control.NumericEditField
        PromotePanel               matlab.ui.container.Panel
        RandomPhaseButten          matlab.ui.control.StateButton
        SphericalPhaseButten       matlab.ui.control.StateButton
        DoublePhaseButten          matlab.ui.control.StateButton
        ParameterPanel             matlab.ui.container.Panel
        iter_number                matlab.ui.control.NumericEditField
        distance                   matlab.ui.control.NumericEditField
        IterationLabel             matlab.ui.control.Label
        ImagingDistanceLabel       matlab.ui.control.Label
        focal_length               matlab.ui.control.NumericEditField
        SphericalFocalLengthLabel  matlab.ui.control.Label
        CalculateButten            matlab.ui.control.Button
        ImportedImagePanel         matlab.ui.container.Panel
        RawImage                   matlab.ui.control.Image
        FourKindsImagePanel        matlab.ui.container.Panel
        RedChannel                 matlab.ui.control.Image
        GreenChannel               matlab.ui.control.Image
        BlueChannel                matlab.ui.control.Image
        Grayscale                  matlab.ui.control.Image
        ResultPanel                matlab.ui.container.Panel
        Image5                     matlab.ui.control.Image
        Panel_7                    matlab.ui.container.Panel
        Image6                     matlab.ui.control.Image
        Button_13                  matlab.ui.control.Button
        Button_14                  matlab.ui.control.Button
        Button_15                  matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        Button_17                  matlab.ui.control.Button
        Button_18                  matlab.ui.control.Button
        Lamp_2                     matlab.ui.control.Lamp
        Lamp_3                     matlab.ui.control.Lamp
        Panel_4                    matlab.ui.container.Panel
        Image3                     matlab.ui.control.Image
        Panel_6                    matlab.ui.container.Panel
        Image4                     matlab.ui.control.Image
        Button_10                  matlab.ui.control.Button
        Button_12                  matlab.ui.control.Button
        red_slider                 matlab.ui.control.Slider
        green_slider               matlab.ui.control.Slider
        Button_16                  matlab.ui.control.Button
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        blue_slider                matlab.ui.control.Slider
        Label_6                    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            global XRGB;
            [nom,chemin]=uigetfile(['*.*'],'调入模拟物体图像');%[ file, path ]
            if [nom,chemin]               
                [img,~] = imread([chemin,nom]); 
                img=imresize(img,[1920,1920]);
                XRGB = img;
                app.RawImage.ImageSource = img;% image imported
                imgR=img;imgR(:,:,2:3)=0;
                imgG=img;imgG(:,:,[1,3])=0;
                imgB=img;imgB(:,:,1:2)=0;
                imggray=rgb2gray(img);
                imgGray(:,:,1)=imggray;imgGray(:,:,2)=imggray;imgGray(:,:,3)=imggray;
                app.RedChannel.ImageSource = imgR; % Red channel
                app.GreenChannel.ImageSource = imgG; % Green channel 
                app.BlueChannel.ImageSource = imgB; % Blue channel
                app.Grayscale.ImageSource = imgGray; % Grayscale
                
                
                % three lamps at lower right corner
                app.Lamp_1.Color=[1 0 0];
                app.Lamp_2.Color=[0.94 0.94 0.94];
                app.Lamp_3.Color=[0.94 0.94 0.94];
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            global XRGB;
            global picture3;global picture4;
            % wavelength 
            lambda{1}=app.Lambda1Value.Value;
            lambda{2}=app.Lambda2Value.Value;
            lambda{3}=app.Lambda3Value.Value;
            
            dist=app.distance.Value;
            iters=app.iter_number.Value;
            f=app.focal_length.Value;
            %几种光
            wavelength{1}=app.Lambda1ChooseButten.Value;
            wavelength{2}=app.Lambda2ChooseButten.Value;
            wavelength{3}=app.Lambda3ChooseButten.Value;
            
            % choose mode
            % mode1: spacedividing
            % mode2: superposition
            mode1=app.Mode1Butten.Value;
            
            %three kinds of image improving methods
            random_phase=app.RandomPhaseButten.Value;
            spherical_phase=app.SphericalPhaseButten.Value;
            double_phase=app.DoublePhaseButten.Value;
            
            app.red_slider.Value = 50;
            app.green_slider.Value = 50;
            app.blue_slider.Value = 50;
            k1=app.red_slider.Value/50;k2=app.green_slider.Value/50;k3=app.blue_slider.Value/50;
            RGB=wavelength{1}+wavelength{2}+wavelength{3};
            
            tempyi=ones(1080,1920/RGB);   %一
            templing=zeros(1080,1920/RGB);%零
            tempyi=0.7.*tempyi;
            [tempm,tempn,~]=size(XRGB);
            
            %%%%%%%%%%%%%%%%%%%%%预期结果%%%%%%%%%%%%%%%%%%%%
            if RGB>1 
                XR = XRGB(:,:,1);XG = XRGB(:,:,2);XB = XRGB(:,:,3);
            end
            if RGB==1 
            if size(XRGB,3) == 3 
                X00 = rgb2gray(XRGB); 
            else 
                X00 = XRGB; 
            end 
            end
            
            ling=zeros(tempm,tempn);
            if RGB==3
                    picture3=cat(3,k1.*XR,k2.*XG,XB);     
                    picture4=[cat(3,tempyi,templing,templing),cat(3,templing,tempyi,templing),cat(3,templing,templing,tempyi)];
            end
            if RGB==2
                if ~wavelength{1}
                        img=cat(3,ling,k2.*XG,XB);
                        picture4=[cat(3,templing,tempyi,templing),cat(3,templing,templing,tempyi)];
                end
                if ~wavelength{2}
                        img=cat(3,k1.*XR,ling,XB);
                        picture4=[cat(3,tempyi,templing,templing),cat(3,templing,templing,tempyi)];
                end
                if ~wavelength{3}
                        img=cat(3,k1.*XR,k2.*XG,ling);
                        picture4=[cat(3,tempyi,templing,templing),cat(3,templing,tempyi,templing)];
                end
                picture3=img;
            end
            if RGB==1
                if wavelength{1}
                    img=cat(3,k1.*X00,ling,ling);
                    picture4=cat(3,0.6.*tempyi,templing,templing);
                end
                 if wavelength{2}
                    img=cat(3,ling,k2.*X00,ling);
                    picture4=cat(3,templing,0.6.*tempyi,templing);
                 end
                 if wavelength{3}
                    img=cat(3,ling,ling,X00);
                    picture4=cat(3,templing,templing,0.6.*tempyi);
                 end
                picture3=img;
            end
            picture3=imresize(picture3,[1000,1000]);
            app.Image3.ImageSource = picture3;
            
            if(~mode1)
                picture4='1.png';
            end
            app.Image4.ImageSource = picture4;
            app.Lamp.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[1 1 0];
            app.Lamp_3.Color=[0.94 0.94 0.94];
        end

        % Button pushed function: CalculateButten
        function CalculateButtenPushed(app, event)
            %计算按钮按下时
            global XRGB;global picture5;global picture6;
            Button_12Pushed(app, event);%刷新          
            %------------------传值--------------------%
            %可编辑文本
            lambda{1}=app.Lambda1Value.Value;
            lambda{2}=app.Lambda2Value.Value;
            lambda{3}=app.Lambda3Value.Value;
            dist=app.distance.Value;
            iters=app.iter_number.Value;
            f=app.focal_length.Value;
            %几种光
            wavelength{1}=app.Lambda1ChooseButten.Value;
            wavelength{2}=app.Lambda2ChooseButten.Value;
            wavelength{3}=app.Lambda3ChooseButten.Value;
            %划分还是叠加
            mode1=app.Mode1Butten.Value;
            %随机，球面，双相位
            random_phase=app.RandomPhaseButten.Value;
            spherical_phase=app.SphericalPhaseButten.Value;
            double_phase=app.DoublePhaseButten.Value;
            %-----------------传值结束------------------%           
            %-----------------分情况计算----------------%
            if mode1
                [picture6,picture5]=calculate_mode1(wavelength,lambda,XRGB,dist,f,random_phase,iters,spherical_phase,double_phase);       
            else
                [picture6,picture5]=calculate_mode2(XRGB,lambda,dist,random_phase,double_phase);
            end
            picture6_color(:,:,1)=picture6;picture6_color(:,:,2)=picture6;picture6_color(:,:,3)=picture6;
            app.Image5.ImageSource = picture5;
            app.Image6.ImageSource = picture6_color;    
            app.Lamp.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[0.94 0.94 0.94];
            app.Lamp_3.Color=[0 1 0];
            %---------------分情况计算完成--------------%
           
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            global picture5;
            figure,imshow(picture5);
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            global picture6;
            figure,imshow(picture6);
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            %保存相息图
            global picture6;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存相息图');
            imwrite(picture6, [pname,fname]);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            %保存相息图
            global picture6;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存相息图');
            imwrite(picture6, [pname,fname]);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            global picture3;
            figure,imshow(picture3);
        end

        % Callback function
        function Button_11Pushed(app, event)
            global picture3;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存预览图');
            imwrite(picture3, [pname,fname]);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            global picture3;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存预览图');
            imwrite(picture3, [pname,fname]);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            global picture5;
            [fname,pname,~] = uiputfile({'*.bmp','BMP (*.bmp)';'*.jpg','JPEG (*.jpg)';'*.tif','TIF (*.tif)'}, '保存模拟图');
            imwrite(picture5, [pname,fname]);
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            global XRGB;global picture3;global picture4;
            %------------------传值--------------------%
            %几种光
            wavelength{1}=app.Lambda1ChooseButten.Value;
            wavelength{2}=app.Lambda2ChooseButten.Value;
            wavelength{3}=app.Lambda3ChooseButten.Value;           
            %-----------------传值结束------------------%
            k1=app.red_slider.Value/50;k2=app.green_slider.Value/50;k3=app.blue_slider.Value/50;
            RGB=wavelength{1}+wavelength{2}+wavelength{3};
            [tempm,tempn,~]=size(XRGB);
            if RGB>1 
                XR = XRGB(:,:,1);XG = XRGB(:,:,2);XB = XRGB(:,:,3);
            end
            if RGB==1 
            if size(XRGB,3) == 3 
                X00 = rgb2gray(XRGB); 
            else 
                X00 = XRGB; 
            end 
            end
            
            ling=zeros(tempm,tempn);
            if RGB==3
                    picture3=cat(3,k1.*XR,k2.*XG,k3.*XB);                   
            end
            if RGB==2
                if ~wavelength{1}
                        img=cat(3,ling,k2.*XG,k3.*XB);
                end
                if ~wavelength{2}
                        img=cat(3,k1.*XR,ling,k3.*XB);
                end
                if ~wavelength{3}
                        img=cat(3,k1.*XR,k2.*XG,ling);
                end
                picture3=img;
            end
            if RGB==1
                if wavelength{1}
                    img=cat(3,k1.*X00,ling,ling);
                end
                 if wavelength{2}
                    img=cat(3,ling,k2.*X00,ling);
                 end
                 if wavelength{3}
                    img=cat(3,ling,ling,k3.*X00);
                 end
                picture3=img;
            end
            picture3=imresize(picture3,[1000,1000]);
            app.Image3.ImageSource = picture3;
            
            app.Lamp.Color=[0.94 0.94 0.94];
            app.Lamp_2.Color=[1 1 0];
            app.Lamp_3.Color=[0.94 0.94 0.94];
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            global picture3;global picture5;
            anlysis_CA(picture3,picture5);
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            global picture3;global picture5;
            anlysis_SNR(picture3,picture5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColorphaseUIFigure = uifigure('Visible', 'off');
            app.ColorphaseUIFigure.Name = 'Color phase';
            app.Menu = uimenu(app.ColorphaseUIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '导入';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = '保存';
            app.Menu_3 = uimenu(app.Menu_4);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '保存相位全息图';
            app.Menu_5 = uimenu(app.Menu_4);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '保存预览图';
            app.Menu_7 = uimenu(app.Menu_4);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '保存模拟图';
            app.Menu_6 = uimenu(app.ColorphaseUIFigure);
            app.Menu_6.Text = '帮助';
            app.Panel = uipanel(app.ColorphaseUIFigure);
            app.ModePanel = uibuttongroup(app.Panel);
            app.ModePanel.Title = 'Mode';
            app.Mode1Butten = uitogglebutton(app.ModePanel);
            app.Mode1Butten.Text = '空间划分';
            app.Mode1Butten.Value = true;
            app.Mode2Butten = uitogglebutton(app.ModePanel);
            app.Mode2Butten.Text = '空间叠加';
            app.WavelengthPanel = uipanel(app.Panel);
            app.WavelengthPanel.Title = 'Lambda（nm）';
            app.Lambda1Value = uieditfield(app.WavelengthPanel, 'numeric');
            app.Lambda1Value.Value = 635;
            app.Lambda1ChooseButten = uibutton(app.WavelengthPanel, 'state');
            app.Lambda1ChooseButten.Text = 'λ1';
            app.Lambda1ChooseButten.Value = true;
            app.Lambda2ChooseButten = uibutton(app.WavelengthPanel, 'state');
            app.Lambda2ChooseButten.Text = 'λ2';
            app.Lambda3ChooseButten = uibutton(app.WavelengthPanel, 'state');
            app.Lambda3ChooseButten.Text = 'λ3';
            app.Lambda2Value = uieditfield(app.WavelengthPanel, 'numeric');
            app.Lambda2Value.Value = 520;
            app.Lambda3Value = uieditfield(app.WavelengthPanel, 'numeric');
            app.Lambda3Value.Value = 450;
            app.PromotePanel = uipanel(app.Panel);
            app.PromotePanel.Title = 'Promote';
            app.RandomPhaseButten = uibutton(app.PromotePanel, 'state');
            app.RandomPhaseButten.Text = '随机';
            app.SphericalPhaseButten = uibutton(app.PromotePanel, 'state');
            app.SphericalPhaseButten.Text = '球面';
            app.SphericalPhaseButten.Value = true;
            app.DoublePhaseButten = uibutton(app.PromotePanel, 'state');
            app.DoublePhaseButten.Text = '双相位';
            app.ParameterPanel = uipanel(app.Panel);
            app.ParameterPanel.Title = 'Put in';
            app.iter_number = uieditfield(app.ParameterPanel, 'numeric');
            app.distance = uieditfield(app.ParameterPanel, 'numeric');
            app.distance.Value = 1600;
            app.IterationLabel = uilabel(app.ParameterPanel);
            app.IterationLabel.Text = '迭代次数';
            app.ImagingDistanceLabel = uilabel(app.ParameterPanel);
            app.ImagingDistanceLabel.Text = '成像距离';
            app.focal_length = uieditfield(app.ParameterPanel, 'numeric');
            app.focal_length.Value = 200;
            app.SphericalFocalLengthLabel = uilabel(app.ParameterPanel);
            app.SphericalFocalLengthLabel.Text = '球面焦距';
            app.CalculateButten = uibutton(app.Panel, 'push');
            app.CalculateButten.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtenPushed, true);
            app.CalculateButten.Text = '计算';
            app.ImportedImagePanel = uipanel(app.ColorphaseUIFigure);
            app.RawImage = uiimage(app.ImportedImagePanel);
            app.RawImage.ImageSource = '叉丝.jpg';
            app.FourKindsImagePanel = uipanel(app.ImportedImagePanel);
            app.RedChannel = uiimage(app.FourKindsImagePanel);
            app.GreenChannel = uiimage(app.FourKindsImagePanel);
            app.BlueChannel = uiimage(app.FourKindsImagePanel);
            app.Grayscale = uiimage(app.FourKindsImagePanel);
            app.ResultPanel = uipanel(app.ColorphaseUIFigure);
            app.Image5 = uiimage(app.ResultPanel);
            app.Panel_7 = uipanel(app.ResultPanel);
            app.Image6 = uiimage(app.Panel_7);
            app.Button_13 = uibutton(app.ResultPanel, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '还原图放大';
            app.Button_14 = uibutton(app.ResultPanel, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '相息图放大';
            app.Button_15 = uibutton(app.ResultPanel, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '保存相息图';
            app.Lamp = uilamp(app.ResultPanel);
            app.Button_17 = uibutton(app.ResultPanel, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '色差分析';
            app.Button_18 = uibutton(app.ResultPanel, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '噪声分析';
            app.Lamp_2 = uilamp(app.ResultPanel);
            app.Lamp_3 = uilamp(app.ResultPanel);
            app.Panel_4 = uipanel(app.ColorphaseUIFigure);
            app.Image3 = uiimage(app.Panel_4);
            app.Panel_6 = uipanel(app.Panel_4);
            app.Image4 = uiimage(app.Panel_6);
            app.Button_10 = uibutton(app.Panel_4, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '预览图放大';
            app.Button_12 = uibutton(app.Panel_4, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '预览';
            app.red_slider = uislider(app.Panel_4);
            app.red_slider.Value = 50;
            app.green_slider = uislider(app.Panel_4);
            app.green_slider.Value = 50;
            app.Button_16 = uibutton(app.Panel_4, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '调色';
            app.Label_4 = uilabel(app.Panel_4);
            app.Label_4.Text = '红';
            app.Label_5 = uilabel(app.Panel_4);
            app.Label_5.Text = '绿';
            app.blue_slider = uislider(app.Panel_4);
            app.blue_slider.Value = 50;
            app.Label_6 = uilabel(app.Panel_4);
            app.Label_6.Text = '蓝';
            % Show the figure after all components are created
            app.ColorphaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = colorphase

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColorphaseUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColorphaseUIFigure)
        end
    end
end